classdef s20LaserTargetPositionsTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UITable            matlab.ui.control.Table
        TargetseLogButton  matlab.ui.control.Button
    end

    
    properties (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../F2_LAA/');
            config = loadPyConfig();
            k = 1;
            sections = fieldnames(config);
            for i=1:numel(sections)
                section = config.(sections{i});
                cameras = fieldnames(section.cameras);
                for j=1:numel(cameras)
                    camera = section.cameras.(cameras{j});
                    target = camera.target;
                    names{k} = cameras{j};
                    bp(k,1) = double(camera.target{1});
                    bp(k,2) = double(camera.target{2});
                    k = k+1;
                end
            end
            bp(:,1:2) = round(bp(:,1:2),2);
            t = table(names',bp(:,1),bp(:,2));
            app.UITable.Data = t;
            
            
        end

        % Button pushed function: TargetseLogButton
        function TargetseLogButtonPushed(app, event)
            addpath('./common/')
            info_str = sprintf(['| Camera Name | X mean Target [pix] | Y mean Target [pix] \n']);
            for i = 1:height(app.UITable.Data)
                name = app.UITable.Data{i,1}{1};
                mat_line = sprintf(['| ' name ' | ' num2str(app.UITable.Data{i,2}) ' | ',...
                num2str(app.UITable.Data{i,3}) '\n']);
                info_str = [info_str, mat_line];
            end
          
            info_str = sprintf([info_str '\n']);
            figure(99);
            ax = axes();
            set(ax, 'Visible', 'off');
            set(gcf,'Position',[10 10 10 10]);
            util_printLog2020(99,'title','S20 Laser Target Position Values',...
            'author','S20 Laser Target Position GUI','text',info_str);
            clf(99), close(99);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Camera Name'; 'Target X [pix]'; 'Target Y [pix]'};
            app.UITable.RowName = {};
            app.TargetseLogButton = uibutton(app.UIFigure, 'push');
            app.TargetseLogButton.ButtonPushedFcn = createCallbackFcn(app, @TargetseLogButtonPushed, true);
            app.TargetseLogButton.Text = 'Targets -> eLog';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = s20LaserTargetPositionsTable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end